classdef viewMRE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MREViewerUIFigure               matlab.ui.Figure
        VibEditField                    matlab.ui.control.EditField
        VibEditFieldLabel               matlab.ui.control.Label
        SavematforBioqicappButton       matlab.ui.control.Button
        ViewDisplacementAmplitudeCheckBox  matlab.ui.control.CheckBox
        AnimationSpeedfpsSlider         matlab.ui.control.Slider
        AnimationSpeedfpsSliderLabel    matlab.ui.control.Label
        StartStopAnimationButton        matlab.ui.control.StateButton
        MDEVInversionButton             matlab.ui.control.Button
        ScreenCaptureButton             matlab.ui.control.Button
        NonrigidMotionCorrectionButton  matlab.ui.control.Button
        filenameEditField               matlab.ui.control.EditField
        filenameLabel                   matlab.ui.control.Label
        MREScanInfoLabel                matlab.ui.control.Label
        PhaseSpinner                    matlab.ui.control.Spinner
        PhaseSpinnerLabel               matlab.ui.control.Label
        VibrationSpinner                matlab.ui.control.Spinner
        VibrationSpinnerLabel           matlab.ui.control.Label
        DirectionSpinner                matlab.ui.control.Spinner
        DirectionSpinnerLabel           matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        SliceSpinnerLabel               matlab.ui.control.Label
        SmoothandUnwrapPhaseButton      matlab.ui.control.Button
        infoTable                       matlab.ui.control.Table
        LoadPARRECDataButton            matlab.ui.control.Button
        phaseAxes_2                     matlab.ui.control.UIAxes
        magnitudeAxes_2                 matlab.ui.control.UIAxes
        phaseAxes                       matlab.ui.control.UIAxes
        timeAxes                        matlab.ui.control.UIAxes
        magnitudeAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data = struct;      % a struct that stores the loaded and updated data
        header;             % the header from read parrec
        inplaneResolution;  % from header, in mm
        inputDir;           % data directory
        filename;           % data file name
        sequenceType        % either GRE or SE-EPI

        % sequence characteristics
        nSlices;
        nVibs;
        nDirs;
        nPhases;

        % current values for figure/plot updates
        currentSl;
        currentVib;
        currentDir;
        currentPhase;
        currentX;
        currentY;
        ViewDisplacementAmplitude;  % 1 is yes, 0 delete figure

        Mask;                       % calculated mask from magnitude image
        animate;                    % the animation state, 1 is playing
        vibFreq;                    % The used vibration frequency (or frequencies)
        tVIB;                       % vibration wave periods
        SampleTimes;                % sample times along the wave period
        timeCorrectionForSlices_us; % slice correction times for dejittering
    end

    methods (Access = private)


        function updateTimeAxes(app)
            dat = squeeze(app.Data.phData(app.currentX,app.currentY,...
                app.currentSl,app.currentVib,app.currentDir,:));
            app.timeAxes.XLabel.String = 'sampling time (ms)';

            if strcmp(app.sequenceType,'SE-EPI')
                plotTimes = app.SampleTimes(:,app.currentSl,app.currentVib,app.currentDir);
            else
                plotTimes = app.SampleTimes;
            end
            plot(app.timeAxes,plotTimes,dat,'-ok','LineWidth',2);
            xlim(app.timeAxes, [0 app.tVIB(app.currentVib)]/1000);
            hold(app.timeAxes,'on');
            xline(app.timeAxes,plotTimes(app.currentPhase),'r');
            hold(app.timeAxes,'off');
        end

        function updateFigureAxes(app)
            % magnitude
            aa = squeeze(app.Data.magData(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase));
            aa = uint8(aa/max(aa(:))*255);
            overlay = zeros(size(app.Data.magData,1),size(app.Data.magData,2));
            overlay(app.currentX-1:app.currentX+1,app.currentY-1:app.currentY+1) = 1; overlay = imbinarize(overlay);
            oo = imoverlay(aa,overlay,'r');
            imshow(oo,[],'Parent',app.magnitudeAxes); %drawnow;
            
            % phase
            aa = squeeze(app.Data.phData(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase));
            cMask = uint8(squeeze(app.Mask(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase)));
            aa = aa - min(aa(:)); aa = uint8(aa/max(aa(:))*255).*cMask;
            oo = imoverlay(aa,overlay,'r');
            imshow(oo,[],'Parent',app.phaseAxes); %drawnow;
            
            
            % add in coronal view, magnitude
            aa = squeeze(app.Data.magData(app.currentX,:,:,app.currentVib,app.currentDir,app.currentPhase));
            aa = uint8(aa/max(aa(:))*255);
            overlay = zeros(size(app.Data.magData,2),app.nSlices);
            if app.currentSl<app.nSlices
                slMarker = app.currentSl:app.currentSl+1;
            else
                slMarker = app.currentSl-1:app.currentSl;
            end
            overlay(app.currentY-1:app.currentY+1,slMarker) = 1; overlay = imbinarize(overlay);
                
            oo = imoverlay(aa,overlay,'r');
            oo = flipud(rot90(oo,-1));
            imshow(oo,[],'Parent',app.magnitudeAxes_2);
            daspect(app.magnitudeAxes_2,[app.inplaneResolution(3)/app.inplaneResolution(2) 1 1]);

             % add in coronal view, phase
            aa = squeeze(app.Data.phData(app.currentX,:,:,app.currentVib,app.currentDir,app.currentPhase));
            cMask = uint8(squeeze(app.Mask(app.currentX,:,:,app.currentVib,app.currentDir,app.currentPhase)));
            aa = aa - min(aa(:)); aa = uint8(aa/max(aa(:))*255).*cMask;
            oo = imoverlay(aa,overlay,'r');
            oo = flipud(rot90(oo,-1));
            imshow(oo,[],'Parent',app.phaseAxes_2);
            daspect(app.phaseAxes_2,[app.inplaneResolution(3)/app.inplaneResolution(2) 1 1]);

        end


        function updateDisplacementAmplitudeFigure(app)

            if app.ViewDisplacementAmplitude
                % open new figure window to view current slice, vibration,
                % direction displacement map
                currDisplacement = real(app.Data.Displacement(:,:,app.currentSl,app.currentVib,app.currentDir));
                maxdisplacement = max(abs(currDisplacement(:))); % for display purposes only [um]

                % set background = black
                aa = find(currDisplacement==0);
                currDisplacement(aa) = -1.5*maxdisplacement;

                figure(100);
                subplot(2,1,1)
                set(gcf,'color',[1 1 1]);
                imagesc(real(currDisplacement));
                caxis([-1 1]*maxdisplacement);
                axis square off
                cMap = brewermap(255,'RdBu');
                cMap = vertcat([0 0 0], cMap);
                colormap(gca,cMap);
                colorbar
                title 'Displacement (\mum)'

                subplot(2,1,2)
                set(gcf,'color',[1 1 1]);
                imagesc(abs(app.Data.Displacement(:,:,app.currentSl,app.currentVib,app.currentDir)));
                caxis([0 1]*maxdisplacement);
                axis square off
                cMap = brewermap(255,'Oranges');
                cMap = vertcat([0 0 0],cMap);
                colormap(gca,cMap);
                colorbar
                title 'Amplitude (\mum)'
            else
                close(figure(100));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPARRECDataButton
        function LoadPARRECDataButtonPushed(app, event)
            app.animate = 0;

            [app.filename,app.inputDir] = uigetfile('*.rec','Select Reconstructed Data(sets)');

            fName = fullfile(app.inputDir,app.filename);
            app.filenameEditField.Value = app.filename;
            %             app.filenameEditField.Editable = 'off';

            % Read in the data and header
            [data, h] = readrec_V4_2(fName);
            app.inplaneResolution = h.pixdim;                         % in mm
            app.nSlices = size(data,3);

            tempMag = permute(data(:,:,:,:,:,1,:),[1 2 3 4 5 7 6]);
            tempPh = permute(data(:,:,:,:,:,2,:),[1 2 3 4 5 7 6]);
            tempPh = tempPh - min(tempPh(:)) - pi;

            % determine if SE-EPI or FFE acquisition
            if strcmp(h.technique,'FFE') % have to reshape the data
                app.sequenceType = 'FFE';
                app.nPhases = size(tempPh,5)/4;     % hard-coded
                app.nVibs = 1;                      % hard-coded
                app.nDirs = 4;                      % hard-coded

                % get vibration frequency from user
                prompt = {'1st:'};
                dlgtitle = 'Enter vibration frequency (Hz)';
                dims = [1 50];
                definput = {'40.00'}';
                answer = inputdlg(prompt(1:app.nVibs),dlgtitle,dims,definput(1:app.nVibs));
                app.vibFreq = round(str2double(answer{1,1}),3);
                app.tVIB = double(1e6./app.vibFreq);
                app.SampleTimes = (0:app.tVIB/app.nPhases:(app.tVIB-app.tVIB/app.nPhases))/1000;
                for iwave = 1:app.nPhases
                    storeMag(:,:,:,:,iwave,:)=tempMag(:,:,:,1,iwave:app.nPhases:end);
                    storePh(:,:,:,:,iwave,:)=tempPh(:,:,:,1,iwave:app.nPhases:end);
                end
                tempMag = permute(storeMag,[1 2 3 4 6 5]);
                tempPh = permute(storePh,[1 2 3 4 6 5]);
                clear storeMag storePh

            else   % SE-EPI
                app.sequenceType = 'SE-EPI';
                app.nPhases = h.nphases;
                app.nVibs = h.nechoes;
                app.nDirs = h.ndynamics;

                % store for sorting
                mag = tempMag;
                ph = tempPh;

                % for R571 (after May 2021), the frequencies are written to the header
                scanDate = strrep(h.datetime(1:10),'.',''); scanDate = str2double([scanDate(5:8) scanDate(3:4) scanDate(1:2)]);
                if scanDate > 20210501
                    answer = unique(h.tbl(:,34));
                else
                    % get vibration frequency from user
                    prompt = {'1st:';'2nd:';'3rd:';'4th:';'5th:';'6th:';};
                    dlgtitle = 'Enter vibration frequencies (Hz)';
                    dims = [1 50];
                    definput = {'30.00';'40.00';'50.00';'60.00';'60.00';'60.00'}';
                    answer = str2double(inputdlg(prompt(1:app.nVibs),dlgtitle,dims,definput(1:app.nVibs)));
                end

                % calculate sequence timing and then reorder the
                % slices based on actual wave timing
                shotdur = 1000*h.tr / app.nSlices;  % in microseconds

                %                 % for interleaved ordering
                %                 first_loc_order = 1:app.nSlices;
                %                 step = round(sqrt(double(app.nSlices)));
                %                 k = 1;
                %                 % note this assumes interleaved scanning
                %                 for s = 1:step
                %                     for loc = s:step:double(app.nSlices)
                %                         loc_order(k) = first_loc_order(loc);
                %                         k = k+1;
                %                     end
                %                 end

                % for reversed central ordering
                shotdur = 1000*h.tr / app.nSlices;  % in microseconds

                steps = app.nSlices-1:-1:1;
                steps(2:2:end) = -steps(2:2:end);
                loc_order = ones(app.nSlices,1);

                for s = 2:length(steps)+1
                    loc_order(s) = loc_order(s-1) + steps(s-1);
                end
                acqtime = (loc_order-1).*shotdur;


                % save acqtime as timeCorrectionForSlices_us for
                % dejittering later
                app.timeCorrectionForSlices_us = zeros(app.nSlices, app.nDirs, app.nVibs);

                %% For one vibration, build up the acquisition time for each slice, as well
                % as the 'wave time' at each point
                Sliceblock_length = app.nDirs*app.nPhases;

                % now loop over the used frequencies to create wave times
                for i = 1:app.nVibs
                    app.vibFreq(i) = round(answer(i),3);
                end
                app.tVIB = double(1e6./app.vibFreq);

                for vib = 1:app.nVibs

                    % make true sample times based on current tVIB and max tVIB
                    trueAcqTime = zeros(app.nSlices,Sliceblock_length);
                    trueAcqTime(:,1) = acqtime;     % set first column as our slice base
                    % the fill times before and after each slice block
                    step = app.tVIB(vib)/app.nPhases;
                    t1_fill_times = repmat(0:step:app.tVIB(vib)-step,[1 app.nDirs]);
                    t2_fill_times = repmat(app.tVIB(vib):-step:0+step,[1 app.nDirs]);

                    for i = 2:Sliceblock_length
                        trueAcqTime(:,i) = trueAcqTime(:,i-1) + t1_fill_times(i) + t2_fill_times(i-1) + h.tr*1000;
                    end

                    % save acqtime as timeCorrectionForSlices_us for
                    % dejittering later
                    app.timeCorrectionForSlices_us(:,:,vib) = trueAcqTime(:,1:app.nPhases:end);

                    for DIR = 1:app.nDirs
                        idx = (app.nPhases*(DIR-1)+1):app.nPhases*DIR;
                        rTimes = int32(trueAcqTime(:,idx));
                        waveTimes_slice = mod(rTimes,app.tVIB(vib));

                        % and finally loop over slices again to get a new order for a given set
                        % of wave offsets, then apply these to our already sorted data
                        for sl = 1:app.nSlices
                            [t,I] = sort(squeeze(waveTimes_slice(sl,:)));
                            % reorder the stored data
                            mag(:,:,sl,vib,DIR,:) = mag(:,:,sl,vib,DIR,I);
                            ph(:,:,sl,vib,DIR,:) = ph(:,:,sl,vib,DIR,I);

                            % store sample times for plotting
                            if mean(diff(t)) == 0
                                tvib = double(app.tVIB(vib))/1000;
                                sTimes(:,sl,vib,DIR) = 0:tvib/app.nPhases:(tvib-tvib/app.nPhases);
                            else
                                sTimes(:,sl,vib,DIR) = double(t)/1000;
                            end

                        end
                    end
                end

                tempMag = mag;
                tempPh = ph;
                app.SampleTimes = sTimes;
            end

            % quick and dirty masking for visualization
            m = mean(tempMag(:,:,:,:),4);
            m = imbinarize(m,0.02*max(m(:))); m = imfill(m,26,'holes');
            [~,m] = regionGrowing(m, [round(size(m,1)/2) round(size(m,2)/2) round(size(m,3)/2)]);
            app.Mask = repmat(m,[1 1 1 app.nVibs app.nDirs app.nPhases]);

            tempPh = tempPh.*app.Mask;

            app.Data.magData = tempMag;
            app.Data.phData = tempPh;
            app.header = h;

            % enable spinners
            app.DirectionSpinner.Enable = 'on';
            app.PhaseSpinner.Enable = 'on';
            app.SliceSpinner.Enable = 'on';
            app.VibrationSpinner.Enable = 'on';

            app.currentSl = ceil(app.nSlices/2);
            if app.nSlices > 1
                app.SliceSpinner.Limits = [1,app.nSlices];
            else
                app.SliceSpinner.Enable = 'off';
            end
            app.SliceSpinner.Value = app.currentSl;

            app.currentVib = 1;
            if app.nVibs > 1
                app.VibrationSpinner.Limits = [1,app.nVibs];
            else
                app.VibrationSpinner.Enable = 'off';
            end
            app.VibrationSpinner.Value = app.currentVib;
            app.VibEditField.Value = num2str(app.vibFreq(app.currentVib));

            app.currentDir = 1;
            if app.nDirs > 1
                app.DirectionSpinner.Limits = [1,app.nDirs];
            else
                app.DirectionSpinner.Enable = 'off';
            end
            app.DirectionSpinner.Limits = [1,app.nDirs];
            app.DirectionSpinner.Value = app.currentDir;

            app.currentPhase = 1;
            app.PhaseSpinner.Limits = [1,app.nPhases];
            app.PhaseSpinner.Value = app.currentPhase;

            %             app.infoTable.Data = cat(2,app.nSlices,app.nDirs,app.nVibs,app.nPhases,round(app.inplaneResolution,2));
            %             TE = h.tbl(1,31);
            app.infoTable.Data = cat(2,cellstr(num2str(app.nSlices)),...
                cellstr(num2str(app.nDirs)),...
                cellstr(num2str(app.nVibs)),...
                cellstr(num2str(app.nPhases)),...
                cellstr([num2str(round(app.inplaneResolution(1),1)) ' x ' num2str(round(app.inplaneResolution(2),1)) ' x ' num2str(round(app.inplaneResolution(3),1))]),...
                cellstr(num2str(round(h.tr,1))));
            %             cellstr([num2str(round(h.tr,1)) ' / ' num2str(round(TE,1))]));
            %             app.infoTable.ColumnWidth = repmat({70},[1 7]);

            app.MREScanInfoLabel.Text = ['MRE Scan Info - ' app.sequenceType];

            % initialize images
            app.currentX = round(app.header.nrows/2);
            app.currentY = round(app.header.ncols/2);
            updateFigureAxes(app);
            updateTimeAxes(app);
            % Displacement/Amplitude not available until after unwrapping
            app.ViewDisplacementAmplitudeCheckBox.Enable = 0;
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentSl = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
            updateDisplacementAmplitudeFigure(app);
        end

        % Value changing function: DirectionSpinner
        function DirectionSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentDir = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
            updateDisplacementAmplitudeFigure(app);
        end

        % Value changing function: VibrationSpinner
        function VibrationSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentVib = changingValue;
            app.VibEditField.Value = num2str(app.vibFreq(app.currentVib));
            updateTimeAxes(app);
            updateFigureAxes(app);
            updateDisplacementAmplitudeFigure(app);
        end

        % Value changing function: PhaseSpinner
        function PhaseSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentPhase = changingValue;
            updateFigureAxes(app);
            updateTimeAxes(app);
            drawnow limitrate;
        end

        % Button pushed function: SmoothandUnwrapPhaseButton
        function SmoothandUnwrapPhaseButtonPushed(app, event)
            app.animate = 0;
            % addpath(genpath(sprintf('/home/%s/lood_storage/divi/Projects/asap/incoming/parrec/MATLAB_Scripts/tempApp/snaphu/',getenv('USER'))));
            addpath(genpath(sprintf('/home/%s/scratch/code/matlab/snaphu/',getenv('USER'))));

            h = waitbar(0, sprintf('Smoothing and unwrapping all phase directions and vibrations...'));
            count = 0;

            % initialize displacement maps
            app.Data.Displacement = zeros([size(app.Data.phData,1) size(app.Data.phData,2) app.nSlices ...
                app.nVibs app.nDirs]);
            for vib = 1:app.nVibs
                phi = permute(app.Data.phData(:,:,:,vib,:,:),[1 2 3 6 5 4]);
                mag = permute(app.Data.magData(:,:,:,vib,:,:),[1 2 3 6 5 4]);

                for Dir = 1:app.nDirs
                    % create complex signal, smooth and unwrap
                    tempPhase = exp(1i*phi(:,:,:,:,Dir));
                    tempMag = mag(:,:,:,:,Dir);

                    % perform 3d smoothing of the phase over each wave
                    % phase
                    temp = zeros(size(tempPhase));
                    for ph = 1:app.nPhases
                        % default smoothing parameters from MDEV script
                        parameters.filter = 'gaussian';
                        parameters.size = [5 5 1]; %[pixel]
                        parameters.sd = 0.65; %[pixel] standard deviation
                        if app.nSlices > 1
                            temp(:,:,:,ph) = smooth3(tempPhase(:,:,:,ph), parameters.filter, parameters.size, parameters.sd);
                        else % nSlice == 1
                            tt = repmat(tempPhase(:,:,:,ph),[1 1 2 1]);
                            tt = smooth3(tt, parameters.filter, parameters.size, parameters.sd);
                            temp(:,:,:,ph) = tt(:,:,1);
                        end
                    end

                    % Perform unwrapping
                    %                     unwrappedPhase = LBE_4D(angle(temp));
                    unwrappedPhase = gunwrap(angle(temp));

                    % temporal alignment and detrending
                    phi(:,:,:,:,Dir) = AlignTemporal(unwrappedPhase,4).*permute(app.Mask(:,:,:,vib,Dir,:),[1 2 3 6 4 5]);
                    %                     phi(:,:,:,:,Dir) = unwrappedPhase.*permute(app.Mask(:,:,:,vib,Dir,:),[1 2 3 6 4 5]);
                    clear temp unwrappedPhase
                    count = count+1;
                    waitbar (count/(app.nVibs*app.nDirs), h)
                end
                app.Data.phData(:,:,:,vib,:,:) = permute(phi,[1 2 3 6 5 4]);

                % while we're here we can quickly calculate the
                %  maps
                % encoding efficiency
                TMEG = 27.78/1000;      % MEG period [s]
                fMEG = 1/TMEG;          % Frequency of the Bipolar MEG [Hz]
                G    = 28;              % Maximal Gradient Strength [mT/m]
                SR   = 200;             % max slew rate [mT/m/ms] on Philips Ingenia
                dt   = G/SR;            % Slope time (G/slew rate) [ms]
                M1   = true;            % is the MEG M1 nulled? if false assume bipolar MEG
                frequency = app.vibFreq(vib);         % the wave frequency [Hz]
                enceff = EncodingEfficiency(frequency, fMEG, G, M1, dt);

                displacement = Displacement(phi, 4);

                % convert displacments from rad to um
                displacement = displacement / enceff;
                app.Data.Displacement(:,:,:,vib,:) = displacement.*app.Mask(:,:,:,vib,:,1);
                clear displacement

            end
            close(h);

            updateFigureAxes(app);
            updateTimeAxes(app);

            app.ViewDisplacementAmplitudeCheckBox.Enable = 1;
        end

        % Button pushed function: NonrigidMotionCorrectionButton
        function NonrigidMotionCorrectionButtonPushed(app, event)
            %         addpath(sprintf('/home/%s/lood_storage/divi/Projects/asap/incoming/parrec/MATLAB_Scripts/tempApp/ElastixFromMatlab/',getenv('USER')));

            % make mag and phase volumes, concatenating all directions,
            % frequencies, and offsets
            mag2 = app.Data.magData(:,:,:,:);
            phase2 = app.Data.phData(:,:,:,:);
            % pad mag2 and phase2 to help the registration
            mag2 = padarray(mag2,[0 0 2 0]);
            phase2 = padarray(phase2,[0 0 2 0]);

            % very first frame/direction/frequency is 'fixed'
            %             fixed = mag2(:,:,:,1);
            % register to the mean of all images
            fixed = mean(mag2,4); fixed = fixed/max(fixed(:));
            nFrames = size(mag2,4);
            MAGout = zeros(size(mag2));
            PHASEout = zeros(size(phase2));
            MAGout(:,:,:,1) = fixed;
            PHASEout(:,:,:,1) = phase2(:,:,:,1);

            %fire up parallel pool
            delete(gcp('nocreate'));
            parpool(8);

            % make a waitbar for the parfor loop
            WaitMessage = parfor_wait(nFrames, 'Waitbar', true);
            parfor i = 1:nFrames
                display(sprintf('Registering frame: %03d of %03d',i,nFrames));
                WaitMessage.Send;

                moving = mag2(:,:,:,i); moving = moving/max(moving(:));
                % add in histogram matching to improve registration
                moving = imhistmatchn(moving,fixed);
                [DisplacementField,RegisteredImage] = imregdemons(moving,fixed,[200 100 50],'AccumulatedFieldSmoothing',2,'PyramidLevels',3,'DisplayWaitBar',false);

                MAGout(:,:,:,i) = RegisteredImage;
                PHASEout(:,:,:,i) = imwarp(phase2(:,:,:,i),DisplacementField);
            end

            %Destroy the object.
            WaitMessage.Destroy;

            % remove the padding
            MAGout = MAGout(:,:,3:end-2,:);
            PHASEout = PHASEout(:,:,3:end-2,:);
            regMag =    reshape(MAGout,[size(MAGout,1),size(MAGout,2),size(MAGout,3),app.nVibs,app.nDirs,app.nPhases]);
            regPh =     reshape(PHASEout,[size(MAGout,1),size(MAGout,2),size(MAGout,3),app.nVibs,app.nDirs,app.nPhases]);
            app.Data.magData = regMag;
            app.Data.phData = regPh;
            clear regMag regPh

            %             % optional plot resolved translations
            %             if sl == 1
            %                 figure(100); clf;
            %             end
            %             plot(app.inplaneResolution(1)*squeeze(transforms(3,1,:)),'k');hold on;
            %             plot(app.inplaneResolution(2)*squeeze(transforms(3,2,:)),'b');
            %             ylim([-app.inplaneResolution(1)*2 app.inplaneResolution(1)*2])
            %             legend('x','y'); ylabel('displacement (mm)'); xlabel('frame number')
            %             title(['slice #' num2str(sl)]); hold off;
            %             pause(0.1)
            % delete parallel pool
            delete(gcp('nocreate'))
            %             close(h);

            updateFigureAxes(app);
            updateTimeAxes(app);

        end

        % Button pushed function: ScreenCaptureButton
        function ScreenCaptureButtonPushed(app, event)
            % Button pushed function: CaptureButton
            robot = java.awt.Robot();
            temp = app.MREViewerUIFigure.Position; % returns position as [left bottom width height]
            allMonPos = get(0,'MonitorPositions');
            curMon = find(temp(1)<(allMonPos(:,1)+allMonPos(:,3)),1,'first');
            curMonHeight = allMonPos(curMon,4)+1;
            pos = [temp(1) curMonHeight-(temp(2)+temp(4)) temp(3)-1 temp(4)]; % [left top width height].... UL X, UL Y, width, height
            rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
            cap = robot.createScreenCapture(rect);
            % Convert to an RGB image
            rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
            imgData = zeros(cap.getHeight,cap.getWidth,3,'uint8');
            imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
            imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
            imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';

            % show figure
            figure(1); clf;
            imshow(imgData);

            % save figure
            name = [app.inputDir '/' app.filename(1:end-4) '_SL' num2str(app.currentSl) ...
                '_DIR' num2str(app.currentDir) '_VIB' num2str(app.currentVib) '_PH' num2str(app.currentPhase) '.png'];
            imwrite(frame2im(getframe(figure(1))),name);

        end

        % Button pushed function: MDEVInversionButton
        function MDEVInversionButtonPushed(app, event)

            resolution = app.inplaneResolution/1000;                % put in meters
            % set default parameters
            parameters.fft.numberOfHarmonics = 1;                   % number of the harmonic after temporal FT
            parameters.radialFilter.lowpassThreshold = 100;         % [1/m] butterworth threshold
            parameters.radialFilter.lowpassOrder = 1;               % butterworth order
            parameters.laplaceInversion.density = 1000;             % [kg/m^3] tissue density
            parameters.laplaceInversion.noiseCorrection = false;    % enable noise correction

            % if SE-EPI and Hadamard encoding (nDirs==4), then we have to
            % transform into x,y,z directions, adapted from DecodePhase by
            % C. Guenther
            if strcmp(app.sequenceType,'SE-EPI') && app.nDirs == 4
                enc_matrix = [1 -1 1 -1;1 1 -1 -1; 1 -1 -1 1; 1 1 1 1].';

                % decoding operation is given by pseudo-inverse
                dec_matrix = pinv(enc_matrix);

                dim_dynamic = 5;
                data1 = app.Data.phData;

                % permute dynamic index to front
                indxs = [dim_dynamic setdiff(1:length(size(data1)),dim_dynamic)];
                data1 = permute(data1,indxs);

                % get size of data & reshape
                sz = size(data1);
                data1 = reshape(data1,size(dec_matrix,2),[]);

                %decoding operation
                data1 = dec_matrix * data1;

                % remove phase offset dimension
                data1 = data1(1:3,:);
                sz(1) = 3;

                % reshape data
                data1 = reshape(data1,sz);

                %permute data back
                data1 = ipermute(data1,indxs);

                app.Data.magData = repmat(mean(app.Data.magData,5),[1 1 1 1 3 1]);
                app.Data.phData = data1;

                % set other parameters
                app.nDirs = 3;
                app.currentDir = 3;
                app.DirectionSpinner.Limits = [1,app.nDirs];
                app.DirectionSpinner.Value = app.currentDir;
                app.Mask(:,:,:,:,4,:) = [];
                updateTimeAxes(app);
                updateFigureAxes(app);
            end

            phData = permute(app.Data.phData, [1 2 3 6 5 4]);
            % unwrap the phase and performe temporal Fourier transformation
            [waveField, secondHarm] = gradwrapFFT(phData, app.inplaneResolution, (parameters.fft)); %size(x,y,s,2,3,4)

            % quick nonlinearity calculation to save
            nonlinearity = (abs(secondHarm)./abs(waveField)).*100;
            clear secondHarm

            % lowpass filtering using butterworth
            shearWaveField = radialFilter(waveField, resolution, parameters.radialFilter);

            % main MDEV inversion using Laplace operator
            [absG, phi, strain] = laplaceInversion(shearWaveField, resolution, app.vibFreq, parameters.laplaceInversion);

            %% View
            %             figure('units','normalized','outerposition',[0 0 1 1]),hold on;
            %             subplot(131)
            %             imagesc(imstackmontage(squeeze(absG))); colormap(gray); axis equal off
            %             title('Magnitude shear modulus (Pa)')
            %             subplot(132)
            %             imagesc(imstackmontage(squeeze(phi))); colormap(gray); is equal off
            %             title('Phase shear modulus  (rad)')
            %             subplot(133)
            %             imagesc(imstackmontage(squeeze(strain))); colormap(jet); axis equal off
            %             title('In-plane strain (rad/m)')

            % save the results in the original folder
            absG = absG.*app.Mask(:,:,:,1,1,1);
            phi  = phi .*app.Mask(:,:,:,1,1,1);
            strain= strain.*app.Mask(:,:,:,1,1,1);

            magnitude = app.Data.magData.*app.Mask;
            saveName = fullfile(app.inputDir,app.filename(1:end-4));
            save(saveName,'magnitude', 'absG', 'strain', 'nonlinearity','phi');
            msgbox(['saved in ' saveName '.mat'],'MDEV save notification')

        end

        % Value changed function: StartStopAnimationButton
        function StartStopAnimationButtonValueChanged(app, event)
            app.animate = app.StartStopAnimationButton.Value;
            while (app.animate)
                % first get current phase
                if app.currentPhase == app.nPhases
                    app.currentPhase = 1;
                else
                    app.currentPhase = app.currentPhase+1;
                end
                app.PhaseSpinner.Value = app.currentPhase;
                updateFigureAxes(app);
                updateTimeAxes(app);
                pause(1/app.AnimationSpeedfpsSlider.Value)
            end
        end

        % Value changed function: ViewDisplacementAmplitudeCheckBox
        function ViewDisplacementAmplitudeCheckBoxValueChanged(app, event)
            app.ViewDisplacementAmplitude = app.ViewDisplacementAmplitudeCheckBox.Value;
            updateDisplacementAmplitudeFigure(app);
        end

        % Button pushed function: SavematforBioqicappButton
        function SavematforBioqicappButtonPushed(app, event)
            fname = [erase(app.filename,'.rec') '_app'];
            temporalWaveField = permute(app.Data.phData, [1 2 3 6 5 4]);
            saveName = fullfile(app.inputDir,fname);
            save(saveName,'temporalWaveField');
            msgbox(['saved in ' saveName '.mat'],'Bioqic .mat save notification')
        end

        % Window button down function: MREViewerUIFigure
        function MREViewerUIFigureWindowButtonDown(app, event)
            if isfield(app.Data,'magData')
                imSize = size(app.Data.magData,1);

                % get axis location in the magnitude axis
                xx = round(app.magnitudeAxes.CurrentPoint);
                % check that point makes sense
                x = xx(3); y = xx(1);
                if x > 0 && x < imSize && y > 0 && y < imSize
                    app.currentX = x;
                    app.currentY = y;
                    updateTimeAxes(app);
                    updateFigureAxes(app);
                else    % try to get points from phase axis instead
                    % get axis location in the magnitude axis
                    xx = round(app.phaseAxes.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize && y > 0 && y < imSize
                        app.currentX = x;
                        app.currentY = y;
                        updateTimeAxes(app);
                        updateFigureAxes(app);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MREViewerUIFigure = uifigure('Visible', 'off');
            app.MREViewerUIFigure.Name = 'MRE Viewer';
            app.MREViewerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MREViewerUIFigureWindowButtonDown, true);
            app.magnitudeAxes = uiaxes(app.MREViewerUIFigure);
            title(app.magnitudeAxes, 'Magnitude Image')
            app.magnitudeAxes.XTick = [];
            app.magnitudeAxes.YTick = [];
            app.timeAxes = uiaxes(app.MREViewerUIFigure);
            title(app.timeAxes, 'Time steps')
            xlabel(app.timeAxes, 'phase number')
            app.phaseAxes = uiaxes(app.MREViewerUIFigure);
            title(app.phaseAxes, 'Phase Image')
            app.phaseAxes.XTick = [];
            app.phaseAxes.YTick = [];
            app.magnitudeAxes_2 = uiaxes(app.MREViewerUIFigure);
            title(app.magnitudeAxes_2, 'coronal view')
            app.magnitudeAxes_2.XTick = [];
            app.magnitudeAxes_2.YTick = [];
            app.phaseAxes_2 = uiaxes(app.MREViewerUIFigure);
            title(app.phaseAxes_2, 'coronal view')
            app.phaseAxes_2.XTick = [];
            app.phaseAxes_2.YTick = [];
            app.LoadPARRECDataButton = uibutton(app.MREViewerUIFigure, 'push');
            app.LoadPARRECDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPARRECDataButtonPushed, true);
            app.LoadPARRECDataButton.Text = 'Load PARREC Data';
            app.infoTable = uitable(app.MREViewerUIFigure);
            app.infoTable.ColumnName = {'Slices'; 'Directions'; 'Vibrations'; 'Phases'; 'resolution (mm)'; 'TR (ms)'};
            app.infoTable.RowName = {};
            app.SmoothandUnwrapPhaseButton = uibutton(app.MREViewerUIFigure, 'push');
            app.SmoothandUnwrapPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothandUnwrapPhaseButtonPushed, true);
            app.SmoothandUnwrapPhaseButton.Text = 'Smooth and Unwrap Phase';
            app.SliceSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.MREViewerUIFigure);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.SliceSpinner.Value = 1;
            app.DirectionSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.DirectionSpinnerLabel.Text = 'Direction';
            app.DirectionSpinner = uispinner(app.MREViewerUIFigure);
            app.DirectionSpinner.ValueChangingFcn = createCallbackFcn(app, @DirectionSpinnerValueChanging, true);
            app.DirectionSpinner.Value = 1;
            app.VibrationSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.VibrationSpinnerLabel.Text = {'Vibration'; ''};
            app.VibrationSpinner = uispinner(app.MREViewerUIFigure);
            app.VibrationSpinner.ValueChangingFcn = createCallbackFcn(app, @VibrationSpinnerValueChanging, true);
            app.VibrationSpinner.Value = 1;
            app.PhaseSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.PhaseSpinnerLabel.Text = {'Phase'; ''};
            app.PhaseSpinner = uispinner(app.MREViewerUIFigure);
            app.PhaseSpinner.ValueChangingFcn = createCallbackFcn(app, @PhaseSpinnerValueChanging, true);
            app.PhaseSpinner.Value = 1;
            app.MREScanInfoLabel = uilabel(app.MREViewerUIFigure);
            app.MREScanInfoLabel.Text = 'MRE Scan Info';
            app.filenameLabel = uilabel(app.MREViewerUIFigure);
            app.filenameLabel.Text = 'filename:';
            app.filenameEditField = uieditfield(app.MREViewerUIFigure, 'text');
            app.filenameEditField.Editable = 'off';
            app.NonrigidMotionCorrectionButton = uibutton(app.MREViewerUIFigure, 'push');
            app.NonrigidMotionCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @NonrigidMotionCorrectionButtonPushed, true);
            app.NonrigidMotionCorrectionButton.Text = 'Non-rigid Motion Correction';
            app.ScreenCaptureButton = uibutton(app.MREViewerUIFigure, 'push');
            app.ScreenCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenCaptureButtonPushed, true);
            app.ScreenCaptureButton.Text = 'Screen Capture';
            app.MDEVInversionButton = uibutton(app.MREViewerUIFigure, 'push');
            app.MDEVInversionButton.ButtonPushedFcn = createCallbackFcn(app, @MDEVInversionButtonPushed, true);
            app.MDEVInversionButton.Text = 'MDEV Inversion';
            app.StartStopAnimationButton = uibutton(app.MREViewerUIFigure, 'state');
            app.StartStopAnimationButton.ValueChangedFcn = createCallbackFcn(app, @StartStopAnimationButtonValueChanged, true);
            app.StartStopAnimationButton.Text = 'Start/Stop Animation';
            app.AnimationSpeedfpsSliderLabel = uilabel(app.MREViewerUIFigure);
            app.AnimationSpeedfpsSliderLabel.Text = {'Animation Speed'; '(fps)'};
            app.AnimationSpeedfpsSlider = uislider(app.MREViewerUIFigure);
            app.AnimationSpeedfpsSlider.Limits = [0.5 8];
            app.AnimationSpeedfpsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AnimationSpeedfpsSlider.MinorTicks = [0 2 6 8 10 12 14 16 18 34];
            app.AnimationSpeedfpsSlider.Value = 4;
            app.ViewDisplacementAmplitudeCheckBox = uicheckbox(app.MREViewerUIFigure);
            app.ViewDisplacementAmplitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewDisplacementAmplitudeCheckBoxValueChanged, true);
            app.ViewDisplacementAmplitudeCheckBox.Enable = 'off';
            app.ViewDisplacementAmplitudeCheckBox.Text = 'View Displacement/Amplitude';
            app.SavematforBioqicappButton = uibutton(app.MREViewerUIFigure, 'push');
            app.SavematforBioqicappButton.ButtonPushedFcn = createCallbackFcn(app, @SavematforBioqicappButtonPushed, true);
            app.SavematforBioqicappButton.Text = 'Save .mat for Bioqic app';
            app.VibEditFieldLabel = uilabel(app.MREViewerUIFigure);
            app.VibEditFieldLabel.Text = 'Hz';
            app.VibEditField = uieditfield(app.MREViewerUIFigure, 'text');
            app.VibEditField.Editable = 'off';
            % Show the figure after all components are created
            app.MREViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewMRE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MREViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MREViewerUIFigure)
        end
    end
end